classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ComenzarsensadoButton           matlab.ui.control.Button
        PorcentajedeerrorenlaestimacinPanel  matlab.ui.container.Panel
        ModelogeneralEditField          matlab.ui.control.EditField
        ModelogeneralEditFieldLabel     matlab.ui.control.Label
        InerciaderotorEditField_3       matlab.ui.control.EditField
        InerciaderotorEditField_3Label  matlab.ui.control.Label
        ConstantedegeneradorEditField_3  matlab.ui.control.EditField
        ConstantedegeneradorEditField_3Label  matlab.ui.control.Label
        FriccinviscosaEditField_3       matlab.ui.control.EditField
        FriccinviscosaEditField_3Label  matlab.ui.control.Label
        ConstantedetorqueEditField_3    matlab.ui.control.EditField
        ConstantedetorqueEditField_3Label  matlab.ui.control.Label
        InductanciaEditField_3          matlab.ui.control.EditField
        InductanciaEditField_3Label     matlab.ui.control.Label
        ResistenciaEditField_3          matlab.ui.control.EditField
        ResistenciaEditField_3Label     matlab.ui.control.Label
        CuentaconcajareductoraPanel     matlab.ui.container.Panel
        Reduccin1EditField              matlab.ui.control.EditField
        Reduccin1EditFieldLabel         matlab.ui.control.Label
        SCheckBox                       matlab.ui.control.CheckBox
        NoCheckBox                      matlab.ui.control.CheckBox
        ParmetrosrealesFichatcnicaPanel  matlab.ui.container.Panel
        NosecuentaconlainformacinCheckBox  matlab.ui.control.CheckBox
        InerciaderotorEditField_2       matlab.ui.control.EditField
        InerciaderotorEditField_2Label  matlab.ui.control.Label
        ConstantedegeneradorEditField_2  matlab.ui.control.EditField
        ConstantedegeneradorEditField_2Label  matlab.ui.control.Label
        FriccinviscosaEditField_2       matlab.ui.control.EditField
        FriccinviscosaEditField_2Label  matlab.ui.control.Label
        ConstantedetorqueEditField_2    matlab.ui.control.EditField
        ConstantedetorqueEditField_2Label  matlab.ui.control.Label
        InductanciaEditField_2          matlab.ui.control.EditField
        InductanciaEditField_2Label     matlab.ui.control.Label
        ResistenciaEditField_2          matlab.ui.control.EditField
        ResistenciaEditField_2Label     matlab.ui.control.Label
        ProbarmodeloButton              matlab.ui.control.Button
        ParmetrosestimadosPanel         matlab.ui.container.Panel
        InerciaderotorEditField         matlab.ui.control.EditField
        InerciaderotorEditFieldLabel    matlab.ui.control.Label
        ConstantedegeneradorEditField   matlab.ui.control.EditField
        ConstantedegeneradorEditFieldLabel  matlab.ui.control.Label
        FriccinviscosaEditField         matlab.ui.control.EditField
        FriccinviscosaEditFieldLabel    matlab.ui.control.Label
        ConstantedetorqueEditField      matlab.ui.control.EditField
        ConstantedetorqueEditFieldLabel  matlab.ui.control.Label
        InductanciaEditField            matlab.ui.control.EditField
        InductanciaEditFieldLabel       matlab.ui.control.Label
        ResistenciaEditField            matlab.ui.control.EditField
        ResistenciaEditFieldLabel       matlab.ui.control.Label
        EstimarParmetrosButton          matlab.ui.control.Button
        PuertoCOMEditField              matlab.ui.control.EditField
        PuertoCOMLabel                  matlab.ui.control.Label
        ConecteelmicrocontroladorLabel  matlab.ui.control.Label
        EstimadordeparmetrosdemotoresDChasta12VLabel  matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % === Estado de captura ===
        captureMode char = 'M'   % 'M' o 'P'
        expectedSamplesP (1,1) double = 10000   % ejemplo: 5s/500us=10000 si P usa 500us
        D2P double = []          % buffer para captura P
        
        % === Líneas para overlay (modelo) ===
        hV_model = []
        hI_model = []
        hW_model = []
        hV_realP = []
        hI_realP = []
        hW_realP = []
        % ===== Serial =====
        s = []
        isConnected (1,1) logical = false
        isCapturing (1,1) logical = false
    
        % ===== Data recibida (igual que tu D2 del script) =====
        D2 double = []              % Nx8
        expectedSamples (1,1) double = 8000   % M80 -> 2 s / 250 us = 8000
        gotSamples (1,1) double = 0
    
        % ===== Handles para graficar en vivo =====
        hV = []
        hI = []
        hW = []
    
        % ===== Parámetros “ficha técnica” (texto -> números) =====
        tech struct = struct('R',NaN,'L',NaN,'Kt',NaN,'B',NaN,'J',NaN,'Ke',NaN)
        est  struct = struct('R',NaN,'L',NaN,'Kt',NaN,'B',NaN,'J',NaN,'Ke',NaN)
        hasTechInfo (1,1) logical = true

        gear struct = struct('hasGear',false,'N',NaN)
        inputsLocked (1,1) logical = false
    end
    
    methods (Access = private)
        function initPlots(app)

            cla(app.UIAxes);  cla(app.UIAxes2); cla(app.UIAxes3);
        
            xlim(app.UIAxes,  [0 2]);
            xlim(app.UIAxes2, [0 2]);
            xlim(app.UIAxes3, [0 2]);
        
            % Crear líneas (primero SIEMPRE)
            app.hV = animatedline(app.UIAxes);                 % Real M
            app.hI = animatedline(app.UIAxes2);
            app.hW = animatedline(app.UIAxes3);
        
            app.hV_realP = animatedline(app.UIAxes);           % Real P
            app.hI_realP = animatedline(app.UIAxes2);
            app.hW_realP = animatedline(app.UIAxes3);
        
            app.hV_model = animatedline(app.UIAxes,  'LineStyle','--');  % Modelo
            app.hI_model = animatedline(app.UIAxes2, 'LineStyle','--');
            app.hW_model = animatedline(app.UIAxes3, 'LineStyle','--');
        
            % Ahora sí: asignar colores/estilos
            co = app.UIAxes.ColorOrder;
        
            app.hV.Color = co(1,:);      app.hI.Color = co(1,:);      app.hW.Color = co(1,:);
            app.hV_realP.Color = co(2,:); app.hI_realP.Color = co(2,:); app.hW_realP.Color = co(2,:);
            app.hV_model.Color = co(3,:); app.hI_model.Color = co(3,:); app.hW_model.Color = co(3,:);
        
            % Títulos / labels
            title(app.UIAxes,'Voltaje V(t)'); xlabel(app.UIAxes,'t (s)'); ylabel(app.UIAxes,'V');
            title(app.UIAxes2,'Corriente I(t)'); xlabel(app.UIAxes2,'t (s)'); ylabel(app.UIAxes2,'A');
            title(app.UIAxes3,'Velocidad angular \omega(t)'); xlabel(app.UIAxes3,'t (s)'); ylabel(app.UIAxes3,'rad/s');
        
            % Estado inicial de UI
            app.EstimarParmetrosButton.Enable = 'off';
            updateGearUI(app);
            app.setResultsEnabled(false);
        
            drawnow;
        end
        
        function connectIfNeeded(app)
            if app.isConnected
                flush(app.s);
                return;
            end
    
            com = strtrim(app.PuertoCOMEditField.Value); % "COM5"
            if isempty(com)
                uialert(app.UIFigure,'Ingrese Puerto COM (ej: COM5).','Falta COM');
                return;
            end
    
            try
                app.s = serialport(com,115200);
                configureTerminator(app.s,"LF");
                flush(app.s);
        
                configureCallback(app.s,"terminator", @(src,evt)onSerialLine(app,src));
                app.isConnected = true;
            catch ME
                uialert(app.UIFigure, ME.message, 'Error abriendo puerto');
                app.isConnected = false;
            
            end
        end
        
        function onSerialLine(app, src)
            if ~app.isCapturing
                try, readline(src); catch, end
                return;
            end
        
            try
                line = strtrim(readline(src));
            catch
                return;
            end
        
            if isempty(line), return; end
        
            v = sscanf(line,'%f');
            if numel(v) ~= 8, return; end
        
            idx = v(1);
            k = idx + 1;
        
            % Límite según modo
            if app.captureMode == 'M'
                kMax = app.expectedSamples;
            else
                kMax = app.expectedSamplesP;
            end
            if k < 1 || k > kMax, return; end
        
            t  = v(2)*1e-6;
            I  = v(4)/1000;
            Vm = v(6)/1000;
            w_out = v(8);
            w = w_out;
        
            % reductora usando valor congelado
            if app.gear.hasGear
                N = app.gear.N;
                if N >= 1
                    w = w_out * N;
                else
                    w = w_out / N;
                end
            end
        
            % guardar según modo
            if app.captureMode == 'M'
                app.D2(k,:) = v(:).';
                app.D2(k,8) = w;
            else
                app.D2P(k,:) = v(:).';
                app.D2P(k,8) = w;
            end
        
            if k > app.gotSamples
                app.gotSamples = k;
            end
        
            % plot según modo
            if app.captureMode == 'M'
                addpoints(app.hV, t, Vm);
                addpoints(app.hI, t, I);
                addpoints(app.hW, t, w);
            else
                %addpoints(app.hV_realP, t,
                %Vm);%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                addpoints(app.hI_realP, t, I);
                addpoints(app.hW_realP, t, w);
            end
        
            if mod(k,50)==0
                drawnow limitrate;
            end
        
            % fin captura
            isDone = (app.captureMode == 'M' && k >= app.expectedSamples) || ...
                     (app.captureMode == 'P' && k >= app.expectedSamplesP);
        
            if isDone
                app.isCapturing = false;
        
                if app.captureMode == 'M'
                    app.D2 = app.D2(1:app.gotSamples,:);
                else
                    app.D2P = app.D2P(1:app.gotSamples,:);
                end
        
                drawnow;
        
                setInputsLocked(app,false);
                app.ComenzarsensadoButton.Enable = 'on';
                app.EstimarParmetrosButton.Enable = 'on';
                
                % Probar modelo SOLO debe habilitarse cuando ya estimaste parámetros
                if app.captureMode == 'M'
                    app.ProbarmodeloButton.Enable = 'off';  % todavía NO
                else
                    app.ProbarmodeloButton.Enable = 'on';   % terminó P -> ya puede re-probar
                end
                
                if app.captureMode == 'P'
                    simulateAndOverlayModel(app);
                end
            end
        end
        function updateGearUI(app)
            noSel = app.NoCheckBox.Value;
            siSel = app.SCheckBox.Value;
        
            if noSel && ~siSel
                % Eligió NO
                app.SCheckBox.Enable = 'off';
                app.Reduccin1EditField.Enable = 'off';
                app.Reduccin1EditField.Value = "";   % opcional: limpiar
                app.NoCheckBox.Enable = 'on';
        
            elseif siSel && ~noSel
                % Eligió SÍ
                app.NoCheckBox.Enable = 'off';
                app.Reduccin1EditField.Enable = 'on';
                app.SCheckBox.Enable = 'on';
        
            elseif ~siSel && ~noSel
                % Ninguno seleccionado
                app.NoCheckBox.Enable = 'on';
                app.SCheckBox.Enable = 'on';
                app.Reduccin1EditField.Enable = 'off';
                app.Reduccin1EditField.Value = "";   % opcional
        
            else
                % Ambos marcados (caso raro): prioriza el último cambio apagando el otro
                % Aquí lo dejamos consistente: si ambos, apaga Reducción y habilita ambos para corregir
                app.NoCheckBox.Value = false;
                app.SCheckBox.Value = false;
                app.NoCheckBox.Enable = 'on';
                app.SCheckBox.Enable = 'on';
                app.Reduccin1EditField.Enable = 'off';
                app.Reduccin1EditField.Value = "";
            end
        end
        function setResultsEnabled(app, en)
            if en
                st = 'on';
            else
                st = 'off';
            end
        
            % Panel Parámetros estimados
            app.ResistenciaEditField.Enable         = st;
            app.InductanciaEditField.Enable         = st;
            app.ConstantedetorqueEditField.Enable   = st;
            app.FriccinviscosaEditField.Enable      = st;
            app.InerciaderotorEditField.Enable      = st;
            app.ConstantedegeneradorEditField.Enable= st;
        
            % Panel % error
            app.ResistenciaEditField_3.Enable         = st;
            app.InductanciaEditField_3.Enable         = st;
            app.ConstantedetorqueEditField_3.Enable   = st;
            app.FriccinviscosaEditField_3.Enable      = st;
            app.InerciaderotorEditField_3.Enable      = st;
            app.ConstantedegeneradorEditField_3.Enable= st;
            app.ModelogeneralEditField.Enable         = st;
        
            % Botón probar modelo
            app.ProbarmodeloButton.Enable = st;
        end
        function s = pctErrStr(~, est, realv)
            if ~isfinite(realv) || realv == 0
                s = 'N/A';
                return;
            end
            if ~isfinite(est)
                s = 'N/A';
                return;
            end
            e = 100 * abs((est - realv) / realv);
            s = sprintf('%.3g%%', e);
        end
        function ok = captureUserInputs(app)
            % Lee y guarda inputs de ficha técnica + reductora en app.tech y app.gear
            ok = true;
        
            % ---- Ficha técnica ----
            app.hasTechInfo = ~app.NosecuentaconlainformacinCheckBox.Value;
        
            if app.hasTechInfo
                app.tech.R  = str2double(app.ResistenciaEditField_2.Value);
                app.tech.L  = str2double(app.InductanciaEditField_2.Value);
                app.tech.Kt = str2double(app.ConstantedetorqueEditField_2.Value);
                app.tech.B  = str2double(app.FriccinviscosaEditField_2.Value);
                app.tech.J  = str2double(app.InerciaderotorEditField_2.Value);
                app.tech.Ke = str2double(app.ConstantedegeneradorEditField_2.Value);
        
                % Validación mínima (puedes endurecerla)
                vals = [app.tech.R app.tech.L app.tech.Kt app.tech.B app.tech.J app.tech.Ke];
                if any(~isfinite(vals))
                    uialert(app.UIFigure,'Complete los parámetros de ficha técnica (valores numéricos).','Ficha técnica incompleta');
                    ok = false; return;
                end
            else
                app.tech = struct('R',NaN,'L',NaN,'Kt',NaN,'B',NaN,'J',NaN,'Ke',NaN);
            end
        
            % ---- Reductora ----
            app.gear.hasGear = app.SCheckBox.Value;
        
            if app.gear.hasGear
                N = str2double(app.Reduccin1EditField.Value);
                if ~isfinite(N) || N <= 0
                    uialert(app.UIFigure,'Ingrese un valor numérico válido (>0) en "Reducción 1".','Reducción inválida');
                    ok = false; return;
                end
                app.gear.N = N;
            else
                app.gear.N = NaN;
            end
        end
        function setInputsLocked(app, locked)
            if locked
                st = 'off';
            else
                st = 'on';
            end
            app.inputsLocked = locked;
        
            % --- Puerto COM (opcional bloquear) ---
            app.PuertoCOMEditField.Enable = st;
        
            % --- Ficha técnica ---
            app.NosecuentaconlainformacinCheckBox.Enable = st;
            app.ResistenciaEditField_2.Enable          = st;
            app.InductanciaEditField_2.Enable          = st;
            app.ConstantedetorqueEditField_2.Enable    = st;
            app.FriccinviscosaEditField_2.Enable       = st;
            app.InerciaderotorEditField_2.Enable       = st;
            app.ConstantedegeneradorEditField_2.Enable = st;
        
            % Si el usuario marcó “no se cuenta con info”, igual deja campos off
            if app.NosecuentaconlainformacinCheckBox.Value
                app.ResistenciaEditField_2.Enable          = 'off';
                app.InductanciaEditField_2.Enable          = 'off';
                app.ConstantedetorqueEditField_2.Enable    = 'off';
                app.FriccinviscosaEditField_2.Enable       = 'off';
                app.InerciaderotorEditField_2.Enable       = 'off';
                app.ConstantedegeneradorEditField_2.Enable = 'off';
            end
        
            % --- Reductora ---
            app.NoCheckBox.Enable = st;
            app.SCheckBox.Enable  = st;
            app.Reduccin1EditField.Enable = st;
        
            % si NO hay reductora, el campo reducción siempre off
            if app.NoCheckBox.Value || ~app.SCheckBox.Value
                app.Reduccin1EditField.Enable = 'off';
            end
        end
        function simulateAndOverlayModel(app)
            % Necesita datos P
            if isempty(app.D2P) || size(app.D2P,1) < 10
                return;
            end
        
            % === Extrae data real (P) ===
            t  = app.D2P(:,2) * 1e-6;     % s
            Vm = app.D2P(:,6) / 1000;     % V (medido en motor)
            I  = app.D2P(:,4) / 1000;     % A
            w  = app.D2P(:,8);            % rad/s (ya corregido a eje motor)
        
            % Tiempo uniforme aproximado
            dt = median(diff(t));
            if ~isfinite(dt) || dt <= 0
                dt = 500e-6;
            end
        
            % === Perfil ideal de entrada para P (12V 1s, 0V 1s, -12V 1s, 0V 2s) ===
            u = zeros(size(t));
            u(t >= 0   & t < 1) =  12;
            u(t >= 2   & t < 3) = -12;
        
            % === Parámetros estimados (de tu panel) ===
            % OJO: acá asumo que ya los guardaste en app.est (si no, usa str2double de los editfields)
            R  = app.est.R;   L  = app.est.L;
            Ke = app.est.Ke;  Kt = app.est.Kt;
            J  = app.est.J;   B  = app.est.B;
        
            % Validación mínima
            if any(~isfinite([R L Ke Kt J B])) || any([R L J] <= 0)
                uialert(app.UIFigure,'Parámetros estimados inválidos para simular modelo.','Modelo');
                return;
            end
        
            % === Simulación por Euler (modelo DC) ===
            % i_dot = (u - R i - Ke w)/L
            % w_dot = (Kt i - B w)/J
            i_m = zeros(size(t));
            w_m = zeros(size(t));
        
            for k = 2:numel(t)
                i_dot = (u(k-1) - R*i_m(k-1) - Ke*w_m(k-1)) / L;
                w_dot = (Kt*i_m(k-1) - B*w_m(k-1)) / J;
                i_m(k) = i_m(k-1) + dt*i_dot;
                w_m(k) = w_m(k-1) + dt*w_dot;
            end
        
            % === Overlay en gráficas (modelo) ===
            clearpoints(app.hV_model); clearpoints(app.hI_model); clearpoints(app.hW_model);
            addpoints(app.hV_model, t, u);      % ideal voltaje aplicado
            addpoints(app.hI_model, t, i_m);    % corriente modelo
            addpoints(app.hW_model, t, w_m);    % omega modelo
            drawnow;
        
            % === Métrica de error de modelo (NRMSE sobre omega) ===
            % NRMSE = RMSE / (rango)
            rmse = sqrt(mean((w - w_m).^2, 'omitnan'));
            rngw = max(w) - min(w);
            if rngw <= 0 || ~isfinite(rngw)
                nrmse = NaN;
            else
                nrmse = rmse / rngw;
            end
        
            % Escribir en tu editfield "Modelo general"
            if isfinite(nrmse)
                app.ModelogeneralEditField.Value = sprintf('NRMSE ω = %.2f %%', 100*nrmse);
            else
                app.ModelogeneralEditField.Value = 'NRMSE ω = NaN';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComenzarsensadoButton
        function ComenzarsensadoButtonPushed(app, event)
            if isempty(app.hV) || ~isgraphics(app.hV)
                initPlots(app);
            end
            
            if app.isCapturing
                return;
            end
            app.captureMode = 'M';

            app.ComenzarsensadoButton.Enable = 'off';            
            ok = captureUserInputs(app);
            if ~ok
                app.ComenzarsensadoButton.Enable = 'on';
                return;
            end
            
            % Bloquear inputs (ya no serán editables los text fields d entrada)
            setInputsLocked(app, true);

            connectIfNeeded(app);
            if ~app.isConnected
                app.ComenzarsensadoButton.Enable = 'on';
                return;
            end
            app.captureMode = 'M';
            % --- Para captura M: ventana de 2 s ---
            xlim(app.UIAxes,  [0 2]);
            xlim(app.UIAxes2, [0 2]);
            xlim(app.UIAxes3, [0 2]);
            % Preparar buffers para M80 (2 s / 250 us = 8000)
            app.expectedSamples = 8000;
            app.D2 = zeros(app.expectedSamples,8);
            app.gotSamples = 0;
        
            % Limpia curvas anteriores (REAL M, REAL P y MODELO)
            clearpoints(app.hV); clearpoints(app.hI); clearpoints(app.hW);
            clearpoints(app.hV_realP); clearpoints(app.hI_realP); clearpoints(app.hW_realP);
            clearpoints(app.hV_model); clearpoints(app.hI_model); clearpoints(app.hW_model);
            drawnow;
        
            app.isCapturing = true;
            app.EstimarParmetrosButton.Enable = 'off';
            flush(app.s);
        
            % Enviar comando EXACTO
            writeline(app.s,"M80");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if app.isConnected && ~isempty(app.s)
                    configureCallback(app.s,"off");
                    flush(app.s);
                    clear app.s              % cierra el serialport realmente
                    app.s = [];              % <- esto cierra el puerto
                    app.isConnected = false;
                end
            catch
            end
            delete(app.UIFigure);
        end

        % Value changed function: NosecuentaconlainformacinCheckBox
        function NosecuentaconlainformacinCheckBoxValueChanged(app, event)
            app.hasTechInfo = ~app.NosecuentaconlainformacinCheckBox.Value;

            if app.hasTechInfo
                en = 'on';
            else
                en = 'off';
                app.tech = struct('R',NaN,'L',NaN,'Kt',NaN,'B',NaN,'J',NaN,'Ke',NaN);
            end
        
            app.ResistenciaEditField_2.Enable = en;
            app.InductanciaEditField_2.Enable = en;
            app.ConstantedetorqueEditField_2.Enable = en;
            app.FriccinviscosaEditField_2.Enable = en;
            app.InerciaderotorEditField_2.Enable = en;
            app.ConstantedegeneradorEditField_2.Enable = en;
            
        end

        % Value changed function: NoCheckBox
        function NoCheckBoxValueChanged(app, event)
            if app.NoCheckBox.Value
                app.SCheckBox.Value = false;
            end
            updateGearUI(app);
        end

        % Value changed function: SCheckBox
        function SCheckBoxValueChanged(app, event)
            if app.SCheckBox.Value
                app.NoCheckBox.Value = false;
            end
            updateGearUI(app);
        end

        % Button pushed function: EstimarParmetrosButton
        function EstimarParmetrosButtonPushed(app, event)
            if isempty(app.D2) || size(app.D2,1) < 200
                uialert(app.UIFigure,'No hay suficientes datos para estimar.','Sin datos');
                return;
            end
        
            % ===== Extraer data de app.D2 (formato tuyo) =====
            t  = app.D2(:,2) * 1e-6;      % s
            I  = app.D2(:,4) / 1000;      % A   (col 4 = mA)
            V  = app.D2(:,6) / 1000;      % V   (col 6 = mV motor)
            w  = app.D2(:,8);             % rad/s (ya corregida por reductora según tu lógica)
        
            % ===== Pre-procesamiento básico 
            k0 = max(5, round(0.02/mean(diff(t))));  % ~20 ms
            t = t(k0:end); I = I(k0:end); V = V(k0:end); w = w(k0:end);
        
            dt = median(diff(t));
            if ~isfinite(dt) || dt <= 0
                uialert(app.UIFigure,'Tiempo inválido en datos.','Error');
                return;
            end
        
            win = 41;
            if win >= numel(t)
                win = max(11, 2*floor(numel(t)/4)+1);
            end
            ord = 3;
        
            I_s = sgolayfilt(I, ord, win);
            w_s = sgolayfilt(w, ord, win);
        
            dI  = gradient(I_s, dt);
            dw  = gradient(w_s, dt);
        
            % ===== 1) LS eléctrico: V = R*I + L*dI + Ke*w =====
            Xe = [I_s, dI, w_s];
            ye = V;
        
            theta_e = Xe \ ye;            % LS
            Rhat  = theta_e(1);
            Lhat  = theta_e(2);
            Kehat = theta_e(3);
        
            % ===== 2) LS mecánico: dw = a1*I + a2*w + a3 =====
            % dw = (Kt/J)*I + (-B/J)*w + (-tau0/J)
            Xm = [I_s, w_s, ones(size(w_s))];
            ym = dw;
        
            beta = Xm \ ym;
            a1 = beta(1);   % Kt/J
            a2 = beta(2);   % -B/J
            a3 = beta(3);   % -tau0/J
        
            % Asumimos Kt = Ke (SI)
            Kthat = Kehat;
        
            % Evitar divisiones raras
            if abs(a1) < 1e-9
                uialert(app.UIFigure,'No se pudo estimar J: a1 ~ 0 (poca excitación o datos malos).','Estimación fallida');
                return;
            end
        
            Jhat = Kthat / a1;
            Bhat = -a2 * Jhat;
            tau0hat = -a3 * Jhat; 
        
            % ===== Mostrar resultados en panel "Parámetros estimados" =====
            app.ResistenciaEditField.Value          = sprintf('%.6g', Rhat);
            app.InductanciaEditField.Value          = sprintf('%.6g', Lhat);
            app.ConstantedegeneradorEditField.Value = sprintf('%.6g', Kehat);
            app.ConstantedetorqueEditField.Value    = sprintf('%.6g', Kthat);
            app.InerciaderotorEditField.Value       = sprintf('%.6g', Jhat);
            app.FriccinviscosaEditField.Value       = sprintf('%.6g', Bhat);
        
            % ===== % error si el usuario sí tiene ficha técnica =====
            if app.hasTechInfo
                % tech: R L Kt B J Ke
                app.ResistenciaEditField_3.Value = app.pctErrStr(Rhat,  app.tech.R);
                app.InductanciaEditField_3.Value = app.pctErrStr(Lhat,  app.tech.L);
                app.ConstantedetorqueEditField_3.Value = app.pctErrStr(Kthat, app.tech.Kt);
                app.FriccinviscosaEditField_3.Value = app.pctErrStr(Bhat,  app.tech.B);
                app.InerciaderotorEditField_3.Value = app.pctErrStr(Jhat,  app.tech.J);
                app.ConstantedegeneradorEditField_3.Value = app.pctErrStr(Kehat, app.tech.Ke);
            else
                % si no hay info, vaciamos
                app.ResistenciaEditField_3.Value = '';
                app.InductanciaEditField_3.Value = '';
                app.ConstantedetorqueEditField_3.Value = '';
                app.FriccinviscosaEditField_3.Value = '';
                app.InerciaderotorEditField_3.Value = '';
                app.ConstantedegeneradorEditField_3.Value = '';
            end
            app.est.R  = Rhat;
            app.est.L  = Lhat;
            app.est.Kt = Kthat;
            app.est.Ke = Kehat;
            app.est.J  = Jhat;
            app.est.B  = Bhat;
            % Limpia gráficas después de estimar (opcional)
            clearpoints(app.hV); clearpoints(app.hI); clearpoints(app.hW);
            clearpoints(app.hV_realP); clearpoints(app.hI_realP); clearpoints(app.hW_realP);
            clearpoints(app.hV_model); clearpoints(app.hI_model); clearpoints(app.hW_model);
            drawnow;
        
            app.setResultsEnabled(true);
            app.ProbarmodeloButton.Enable = 'on';
        
        end

        % Button pushed function: ProbarmodeloButton
        function ProbarmodeloButtonPushed(app, event)
            if app.isCapturing
                return;
            end
        
            connectIfNeeded(app);
            if ~app.isConnected
                return;
            end
        
            % Debe haber parámetros estimados
            if isempty(app.ResistenciaEditField.Value)
                uialert(app.UIFigure,'Primero estime parámetros.','Falta estimación');
                return;
            end
        
            % preparar plots
            if isempty(app.hV) || ~isvalid(app.hV)
                initPlots(app);
            end
        
            clearpoints(app.hV); clearpoints(app.hI); clearpoints(app.hW);
            clearpoints(app.hV_realP); clearpoints(app.hI_realP); clearpoints(app.hW_realP);
            clearpoints(app.hV_model); clearpoints(app.hI_model); clearpoints(app.hW_model);
            % --- Para prueba P: ventana de 5 s ---
            xlim(app.UIAxes,  [0 5]);
            xlim(app.UIAxes2, [0 5]);
            xlim(app.UIAxes3, [0 5]);
            drawnow;
        
            % fijar modo y buffers
            app.captureMode = 'P';
            app.expectedSamplesP = 10000;  % <-- AJUSTA según firmware
            app.D2P = zeros(app.expectedSamplesP,8);
            app.gotSamples = 0;
        
            % bloquear UI durante prueba
            app.ComenzarsensadoButton.Enable = 'off';
            app.EstimarParmetrosButton.Enable = 'off';
            app.ProbarmodeloButton.Enable = 'off';
            setInputsLocked(app,true);
        
            app.isCapturing = true;
            flush(app.s);
        
            % Enviar comando EXACTO
            writeline(app.s,"P");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Voltaje  V(t)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Voltaje')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Corriente I(t)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Corriente')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Velocidad angular w(t)')
            xlabel(app.UIAxes3, 'Tiempo')
            ylabel(app.UIAxes3, 'Velocidad angular')
            zlabel(app.UIAxes3, 'Z')
            app.EstimadordeparmetrosdemotoresDChasta12VLabel = uilabel(app.UIFigure);
            app.EstimadordeparmetrosdemotoresDChasta12VLabel.Text = 'Estimador de parámetros de motores DC hasta 12V';
            app.ConecteelmicrocontroladorLabel = uilabel(app.UIFigure);
            app.ConecteelmicrocontroladorLabel.Text = 'Conecte el microcontrolador ...';
            app.PuertoCOMLabel = uilabel(app.UIFigure);
            app.PuertoCOMLabel.Text = 'Puerto COM:';
            app.PuertoCOMEditField = uieditfield(app.UIFigure, 'text');
            app.EstimarParmetrosButton = uibutton(app.UIFigure, 'push');
            app.EstimarParmetrosButton.ButtonPushedFcn = createCallbackFcn(app, @EstimarParmetrosButtonPushed, true);
            app.EstimarParmetrosButton.Text = 'Estimar Parámetros';
            app.ParmetrosestimadosPanel = uipanel(app.UIFigure);
            app.ParmetrosestimadosPanel.Title = 'Parámetros estimados';
            app.ResistenciaEditFieldLabel = uilabel(app.ParmetrosestimadosPanel);
            app.ResistenciaEditFieldLabel.Text = 'Resistencia';
            app.ResistenciaEditField = uieditfield(app.ParmetrosestimadosPanel, 'text');
            app.ResistenciaEditField.Editable = 'off';
            app.InductanciaEditFieldLabel = uilabel(app.ParmetrosestimadosPanel);
            app.InductanciaEditFieldLabel.Text = 'Inductancia';
            app.InductanciaEditField = uieditfield(app.ParmetrosestimadosPanel, 'text');
            app.InductanciaEditField.Editable = 'off';
            app.ConstantedetorqueEditFieldLabel = uilabel(app.ParmetrosestimadosPanel);
            app.ConstantedetorqueEditFieldLabel.Text = 'Constante de torque';
            app.ConstantedetorqueEditField = uieditfield(app.ParmetrosestimadosPanel, 'text');
            app.ConstantedetorqueEditField.Editable = 'off';
            app.FriccinviscosaEditFieldLabel = uilabel(app.ParmetrosestimadosPanel);
            app.FriccinviscosaEditFieldLabel.Text = 'Fricción viscosa';
            app.FriccinviscosaEditField = uieditfield(app.ParmetrosestimadosPanel, 'text');
            app.FriccinviscosaEditField.Editable = 'off';
            app.ConstantedegeneradorEditFieldLabel = uilabel(app.ParmetrosestimadosPanel);
            app.ConstantedegeneradorEditFieldLabel.Text = 'Constante de generador';
            app.ConstantedegeneradorEditField = uieditfield(app.ParmetrosestimadosPanel, 'text');
            app.ConstantedegeneradorEditField.Editable = 'off';
            app.InerciaderotorEditFieldLabel = uilabel(app.ParmetrosestimadosPanel);
            app.InerciaderotorEditFieldLabel.Text = 'Inercia de rotor';
            app.InerciaderotorEditField = uieditfield(app.ParmetrosestimadosPanel, 'text');
            app.InerciaderotorEditField.Editable = 'off';
            app.ProbarmodeloButton = uibutton(app.UIFigure, 'push');
            app.ProbarmodeloButton.ButtonPushedFcn = createCallbackFcn(app, @ProbarmodeloButtonPushed, true);
            app.ProbarmodeloButton.Text = 'Probar modelo';
            app.ParmetrosrealesFichatcnicaPanel = uipanel(app.UIFigure);
            app.ParmetrosrealesFichatcnicaPanel.Title = 'Parámetros reales (Ficha técnica)';
            app.ResistenciaEditField_2Label = uilabel(app.ParmetrosrealesFichatcnicaPanel);
            app.ResistenciaEditField_2Label.Text = 'Resistencia';
            app.ResistenciaEditField_2 = uieditfield(app.ParmetrosrealesFichatcnicaPanel, 'text');
            app.InductanciaEditField_2Label = uilabel(app.ParmetrosrealesFichatcnicaPanel);
            app.InductanciaEditField_2Label.Text = 'Inductancia';
            app.InductanciaEditField_2 = uieditfield(app.ParmetrosrealesFichatcnicaPanel, 'text');
            app.ConstantedetorqueEditField_2Label = uilabel(app.ParmetrosrealesFichatcnicaPanel);
            app.ConstantedetorqueEditField_2Label.Text = 'Constante de torque';
            app.ConstantedetorqueEditField_2 = uieditfield(app.ParmetrosrealesFichatcnicaPanel, 'text');
            app.FriccinviscosaEditField_2Label = uilabel(app.ParmetrosrealesFichatcnicaPanel);
            app.FriccinviscosaEditField_2Label.Text = 'Fricción viscosa';
            app.FriccinviscosaEditField_2 = uieditfield(app.ParmetrosrealesFichatcnicaPanel, 'text');
            app.ConstantedegeneradorEditField_2Label = uilabel(app.ParmetrosrealesFichatcnicaPanel);
            app.ConstantedegeneradorEditField_2Label.Text = 'Constante de generador';
            app.ConstantedegeneradorEditField_2 = uieditfield(app.ParmetrosrealesFichatcnicaPanel, 'text');
            app.InerciaderotorEditField_2Label = uilabel(app.ParmetrosrealesFichatcnicaPanel);
            app.InerciaderotorEditField_2Label.Text = 'Inercia de rotor';
            app.InerciaderotorEditField_2 = uieditfield(app.ParmetrosrealesFichatcnicaPanel, 'text');
            app.NosecuentaconlainformacinCheckBox = uicheckbox(app.ParmetrosrealesFichatcnicaPanel);
            app.NosecuentaconlainformacinCheckBox.ValueChangedFcn = createCallbackFcn(app, @NosecuentaconlainformacinCheckBoxValueChanged, true);
            app.NosecuentaconlainformacinCheckBox.Text = 'No se cuenta con la información';
            app.CuentaconcajareductoraPanel = uipanel(app.UIFigure);
            app.CuentaconcajareductoraPanel.Title = '¿Cuenta con caja reductora?';
            app.NoCheckBox = uicheckbox(app.CuentaconcajareductoraPanel);
            app.NoCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoCheckBoxValueChanged, true);
            app.NoCheckBox.Text = 'No';
            app.SCheckBox = uicheckbox(app.CuentaconcajareductoraPanel);
            app.SCheckBox.ValueChangedFcn = createCallbackFcn(app, @SCheckBoxValueChanged, true);
            app.SCheckBox.Text = 'Sí';
            app.Reduccin1EditFieldLabel = uilabel(app.CuentaconcajareductoraPanel);
            app.Reduccin1EditFieldLabel.Text = 'Reducción 1:';
            app.Reduccin1EditField = uieditfield(app.CuentaconcajareductoraPanel, 'text');
            app.PorcentajedeerrorenlaestimacinPanel = uipanel(app.UIFigure);
            app.PorcentajedeerrorenlaestimacinPanel.Title = 'Porcentaje de error en la estimación';
            app.ResistenciaEditField_3Label = uilabel(app.PorcentajedeerrorenlaestimacinPanel);
            app.ResistenciaEditField_3Label.Text = 'Resistencia';
            app.ResistenciaEditField_3 = uieditfield(app.PorcentajedeerrorenlaestimacinPanel, 'text');
            app.ResistenciaEditField_3.Editable = 'off';
            app.InductanciaEditField_3Label = uilabel(app.PorcentajedeerrorenlaestimacinPanel);
            app.InductanciaEditField_3Label.Text = 'Inductancia';
            app.InductanciaEditField_3 = uieditfield(app.PorcentajedeerrorenlaestimacinPanel, 'text');
            app.InductanciaEditField_3.Editable = 'off';
            app.ConstantedetorqueEditField_3Label = uilabel(app.PorcentajedeerrorenlaestimacinPanel);
            app.ConstantedetorqueEditField_3Label.Text = 'Constante de torque';
            app.ConstantedetorqueEditField_3 = uieditfield(app.PorcentajedeerrorenlaestimacinPanel, 'text');
            app.ConstantedetorqueEditField_3.Editable = 'off';
            app.FriccinviscosaEditField_3Label = uilabel(app.PorcentajedeerrorenlaestimacinPanel);
            app.FriccinviscosaEditField_3Label.Text = 'Fricción viscosa';
            app.FriccinviscosaEditField_3 = uieditfield(app.PorcentajedeerrorenlaestimacinPanel, 'text');
            app.FriccinviscosaEditField_3.Editable = 'off';
            app.ConstantedegeneradorEditField_3Label = uilabel(app.PorcentajedeerrorenlaestimacinPanel);
            app.ConstantedegeneradorEditField_3Label.Text = 'Constante de generador';
            app.ConstantedegeneradorEditField_3 = uieditfield(app.PorcentajedeerrorenlaestimacinPanel, 'text');
            app.ConstantedegeneradorEditField_3.Editable = 'off';
            app.InerciaderotorEditField_3Label = uilabel(app.PorcentajedeerrorenlaestimacinPanel);
            app.InerciaderotorEditField_3Label.Text = 'Inercia de rotor';
            app.InerciaderotorEditField_3 = uieditfield(app.PorcentajedeerrorenlaestimacinPanel, 'text');
            app.InerciaderotorEditField_3.Editable = 'off';
            app.ModelogeneralEditFieldLabel = uilabel(app.PorcentajedeerrorenlaestimacinPanel);
            app.ModelogeneralEditFieldLabel.Text = 'Modelo general:';
            app.ModelogeneralEditField = uieditfield(app.PorcentajedeerrorenlaestimacinPanel, 'text');
            app.ModelogeneralEditField.Editable = 'off';
            app.ComenzarsensadoButton = uibutton(app.UIFigure, 'push');
            app.ComenzarsensadoButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarsensadoButtonPushed, true);
            app.ComenzarsensadoButton.Text = 'Comenzar sensado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end